classdef MultiClamp700AGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        ButtonGroup     matlab.ui.container.ButtonGroup
        ToggleButton    matlab.ui.control.ToggleButton
        ToggleButton2   matlab.ui.control.ToggleButton
        LabelDropDown   matlab.ui.control.Label
        DropDown        matlab.ui.control.DropDown
        ButtonGroup2    matlab.ui.container.ButtonGroup
        ToggleButton4   matlab.ui.control.ToggleButton
        ToggleButton5   matlab.ui.control.ToggleButton
        LabelDropDown2  matlab.ui.control.Label
        DropDown2       matlab.ui.control.DropDown
        RefreshButton   matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setacqpref('MC700AGUIstatus','status',1);
            m = getacqpref('MC700AGUIstatus','mode');
            switch m
                case 'VClamp'
                    app.ButtonGroup.SelectedObject = app.ToggleButton;
                    g = getacqpref('MC700AGUIstatus','VClamp_gain');
                    app.DropDown2.Value = g;
                case 'IClamp'
                    app.ButtonGroup.SelectedObject = app.ToggleButton2;
                    g = getacqpref('MC700AGUIstatus','IClamp_gain');
                    app.DropDown2.Value = g;
            end
            
            m = getacqpref('MC700AGUIstatus','mode2');
            
            switch m
                case 'VClamp'
                    app.ButtonGroup2.SelectedObject = app.ToggleButton4;
                    g = getacqpref('MC700AGUIstatus','VClamp_gain2');
                    app.DropDown2.Value = g;
                case 'IClamp'
                    app.ButtonGroup2.SelectedObject = app.ToggleButton5;
                    g = getacqpref('MC700AGUIstatus','IClamp_gain2');
                    app.DropDown2.Value = g;
            end                    
        end

        % Selection changed function: ButtonGroup
        function ButtonSelectionChange(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            setacqpref('MC700AGUIstatus','mode',selectedButton.Text);
            
            switch selectedButton.Text
                case 'VClamp'
                    value = getacqpref('MC700AGUIstatus','VClamp_gain');
                    app.DropDown.Value = value;
                case 'IClamp'
                    value = getacqpref('MC700AGUIstatus','IClamp_gain');
                    app.DropDown.Value = value;
            end                                
            
        end

        % Selection changed function: ButtonGroup2
        function ButtonGroup2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup2.SelectedObject;
            setacqpref('MC700AGUIstatus','mode2',selectedButton.Text);
            
            switch selectedButton.Text
                case 'VClamp'
                    value = getacqpref('MC700AGUIstatus','VClamp_gain2');
                    app.DropDown2.Value = value;
                case 'IClamp'
                    value = getacqpref('MC700AGUIstatus','IClamp_gain2');
                    app.DropDown2.Value = value;
            end                                
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            setacqpref('MC700AGUIstatus','status',0);
            delete(app);
        end

        % Value changed function: DropDown2
        function DropDown2ValueChanged(app, event)
            value = app.DropDown2.Value;
            selectedButton = app.ButtonGroup2.SelectedObject;
            
            switch selectedButton.Text
                case 'VClamp'
                    setacqpref('MC700AGUIstatus','VClamp_gain2',value);
                case 'IClamp'
                    setacqpref('MC700AGUIstatus','IClamp_gain2',value);
            end                                
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            selectedButton = app.ButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'VClamp'
                    setacqpref('MC700AGUIstatus','VClamp_gain',value);
                case 'IClamp'
                    setacqpref('MC700AGUIstatus','IClamp_gain',value);
            end                                
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            m = getacqpref('MC700AGUIstatus','mode');
            switch m
                case 'VClamp'
                    app.ButtonGroup.SelectedObject = app.ToggleButton;
                    g = getacqpref('MC700AGUIstatus','VClamp_gain');
                    app.DropDown2.Value = g;
                case 'IClamp'
                    app.ButtonGroup.SelectedObject = app.ToggleButton2;
                    g = getacqpref('MC700AGUIstatus','IClamp_gain');
                    app.DropDown2.Value = g;
            end
            
            m = getacqpref('MC700AGUIstatus','mode2');
            
            switch m
                case 'VClamp'
                    app.ButtonGroup2.SelectedObject = app.ToggleButton4;
                    g = getacqpref('MC700AGUIstatus','VClamp_gain2');
                    app.DropDown2.Value = g;
                case 'IClamp'
                    app.ButtonGroup2.SelectedObject = app.ToggleButton5;
                    g = getacqpref('MC700AGUIstatus','IClamp_gain2');
                    app.DropDown2.Value = g;
            end                    
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonSelectionChange, true);
            app.ButtonGroup.Title = 'MultiClamp700A';
            app.ToggleButton = uitogglebutton(app.ButtonGroup);
            app.ToggleButton.Text = 'VClamp';
            app.ToggleButton.Value = true;
            app.ToggleButton2 = uitogglebutton(app.ButtonGroup);
            app.ToggleButton2.Text = 'IClamp';
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Gain';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'2', '5', '10', '20', '50', '100', '200'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '200';
            app.ButtonGroup2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup2SelectionChanged, true);
            app.ButtonGroup2.Title = 'MultiClamp700AAux';
            app.ToggleButton4 = uitogglebutton(app.ButtonGroup2);
            app.ToggleButton4.Text = 'VClamp';
            app.ToggleButton4.Value = true;
            app.ToggleButton5 = uitogglebutton(app.ButtonGroup2);
            app.ToggleButton5.Text = 'IClamp';
            app.LabelDropDown2 = uilabel(app.UIFigure);
            app.LabelDropDown2.Text = 'Gain';
            app.DropDown2 = uidropdown(app.UIFigure);
            app.DropDown2.Items = {'2', '5', '10', '20', '50', '100', '200'};
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            app.DropDown2.Value = '2';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MultiClamp700AGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end