classdef MultiClamp700AGUI_Buttons < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure                % UI Figure
        ButtonGroup    matlab.ui.container.ButtonGroup % MultiClamp700A
        ToggleButton   matlab.ui.control.ToggleButton  % VClamp
        ToggleButton2  matlab.ui.control.ToggleButton  % IClamp
        LabelDropDown  matlab.ui.control.Label         % Gain
        DropDown       matlab.ui.control.DropDown      % 2, 5, 10, 20, 50
        ButtonGroup2   matlab.ui.container.ButtonGroup % MultiClamp700AAux
        ToggleButton4  matlab.ui.control.ToggleButton  % VClamp
        ToggleButton5  matlab.ui.control.ToggleButton  % IClamp
        LabelDropDown2 matlab.ui.control.Label         % Gain
        DropDown2      matlab.ui.control.DropDown      % 2, 5, 10, 20, 50
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setpref('MC700AGUIstatus','status',1)
            m = getpref('MC700AGUIstatus','mode');
            switch m
                case 'VClamp'
                    app.ButtonGroup.SelectedObject = app.ToggleButton;
                    g = getpref('MC700AGUIstatus','VClamp_gain');
                    app.DropDown2.Value = g;
                case 'IClamp'
                    app.ButtonGroup.SelectedObject = app.ToggleButton2;
                    g = getpref('MC700AGUIstatus','IClamp_gain');
                    app.DropDown2.Value = g;
            end
            
            m = getpref('MC700AGUIstatus','mode2');
            
            switch m
                case 'VClamp'
                    app.ButtonGroup2.SelectedObject = app.ToggleButton4;
                    g = getpref('MC700AGUIstatus','VClamp_gain2');
                    app.DropDown2.Value = g;
                case 'IClamp'
                    app.ButtonGroup2.SelectedObject = app.ToggleButton5;
                    g = getpref('MC700AGUIstatus','IClamp_gain2');
                    app.DropDown2.Value = g;
            end                    
        end

        % ButtonGroup selection change function
        function ButtonSelectionChange(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            setpref('MC700AGUIstatus','mode',selectedButton.Text)
            
            switch selectedButton.Text
                case 'VClamp'
                    value = getpref('MC700AGUIstatus','VClamp_gain');
                    app.DropDown.Value = value;
                case 'IClamp'
                    value = getpref('MC700AGUIstatus','IClamp_gain');
                    app.DropDown.Value = value;
            end                                
            
        end

        % ButtonGroup2 selection change function
        function ButtonGroup2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup2.SelectedObject;
            setpref('MC700AGUIstatus','mode2',selectedButton.Text)
            
            switch selectedButton.Text
                case 'VClamp'
                    value = getpref('MC700AGUIstatus','VClamp_gain2');
                    app.DropDown2.Value = value;
                case 'IClamp'
                    value = getpref('MC700AGUIstatus','IClamp_gain2');
                    app.DropDown2.Value = value;
            end                                
            
        end

        % UIFigure close request function
        function UIFigureCloseRequest(app)
            setpref('MC700AGUIstatus','status',0)
            delete(app);
        end

        % DropDown2 value changed function
        function DropDown2ValueChanged(app, event)
            value = app.DropDown2.Value;
            selectedButton = app.ButtonGroup2.SelectedObject;
            
            switch selectedButton.Text
                case 'VClamp'
                    setpref('MC700AGUIstatus','VClamp_gain2',value);
                case 'IClamp'
                    setpref('MC700AGUIstatus','IClamp_gain2',value);
            end                                
            
        end

        % DropDown value changed function
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            selectedButton = app.ButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'VClamp'
                    setpref('MC700AGUIstatus','VClamp_gain',value);
                case 'IClamp'
                    setpref('MC700AGUIstatus','IClamp_gain',value);
            end                                
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest);
            setAutoResize(app, app.UIFigure, true)
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonSelectionChange, true);
            app.ButtonGroup.Title = 'MultiClamp700A';
            app.ButtonGroup.Units = 'pixels';
            app.ToggleButton = uitogglebutton(app.ButtonGroup);
            app.ToggleButton.Text = 'VClamp';
            app.ToggleButton.Value = true;
            app.ToggleButton2 = uitogglebutton(app.ButtonGroup);
            app.ToggleButton2.Text = 'IClamp';
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Gain';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'2', '5', '10', '20', '50'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '50';
            app.ButtonGroup2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup2SelectionChanged, true);
            app.ButtonGroup2.Title = 'MultiClamp700AAux';
            app.ButtonGroup2.Units = 'pixels';
            app.ToggleButton4 = uitogglebutton(app.ButtonGroup2);
            app.ToggleButton4.Text = 'VClamp';
            app.ToggleButton4.Value = true;
            app.ToggleButton5 = uitogglebutton(app.ButtonGroup2);
            app.ToggleButton5.Text = 'IClamp';
            app.LabelDropDown2 = uilabel(app.UIFigure);
            app.LabelDropDown2.Text = 'Gain';
            app.DropDown2 = uidropdown(app.UIFigure);
            app.DropDown2.Items = {'2', '5', '10', '20', '50'};
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            app.DropDown2.Value = '2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MultiClamp700AGUI_Buttons()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end