classdef MultiClamp700AGUI_Tab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure             % MC700A
        TabGroup       matlab.ui.container.TabGroup % VClamp, IClamp
        Tab            matlab.ui.container.Tab      % VClamp
        LabelDropDown  matlab.ui.control.Label      % Gain
        DropDown       matlab.ui.control.DropDown   % 5, 10, 20, 50
        Tab2           matlab.ui.container.Tab      % IClamp
        LabelDropDown2 matlab.ui.control.Label      % Gain
        DropDown2      matlab.ui.control.DropDown   % 20, 50
        TabGroup2      matlab.ui.container.TabGroup % VClamp, IClamp
        Tab3           matlab.ui.container.Tab      % VClamp
        Label          matlab.ui.control.Label      % Gain
        DropDown3      matlab.ui.control.DropDown   % 5, 10, 20, 50
        Tab4           matlab.ui.container.Tab      % IClamp
        Label2         matlab.ui.control.Label      % Gain
        DropDown4      matlab.ui.control.DropDown   % 20, 50
        Label3         matlab.ui.control.Label      % MultiClamp700A
        Label4         matlab.ui.control.Label      % MultiClamp700AAux
    end

    
    methods (Access = public)
        
        function m = mode(app)
            m = app.TabGroup.SelectedTab.Title;
        end
        
        function m = mode2(app)
            m = app.TabGroup2.SelectedTab.Title;
        end
        
        function g = gain(app)
            
            switch app.TabGroup.SelectedTab.Title
                case 'VClamp'
                    g = app.DropDown.Value;
                case 'IClamp'
                    g = app.DropDown2.Value;
            end
            
        end
        
        function g = gain2(app)
            switch app.TabGroup2.SelectedTab.Title
                case 'VClamp'
                    g = app.DropDown3.Value;
                case 'IClamp'
                    g = app.DropDown4.Value;
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            setpref('MC700AGUIstatus','status',1)
            
            % set up the preferences if they aren't there
            if ~ispref('MC700AGUIstatus','mode')
                setpref('MC700AGUIstatus','mode','VClamp')
            end
            if ~ispref('MC700AGUIstatus','mode2')
                setpref('MC700AGUIstatus','mode2','VClamp')
            end
            if ~ispref('MC700AGUIstatus','VClamp_gain')
                setpref('MC700AGUIstatus','VClamp_gain','5')
            end
            if ~ispref('MC700AGUIstatus','IClamp_gain')
                setpref('MC700AGUIstatus','IClamp_gain','5')
            end
            if ~ispref('MC700AGUIstatus','VClamp_gain2')
                setpref('MC700AGUIstatus','VClamp_gain2','5')
            end
            if ~ispref('MC700AGUIstatus','IClamp_gain2')
                setpref('MC700AGUIstatus','IClamp_gain2','5')
            end
            
            m = getpref('MC700AGUIstatus','mode');
            
            
            switch m
                case 'VClamp'
                    app.TabGroup.SelectedTab = app.Tab;
                    g = getpref('MC700AGUIstatus','VClamp_gain');
                    app.DropDown.Value = g;
                case 'IClamp'
                    app.TabGroup.SelectedTab = app.Tab2;
                    g = getpref('MC700AGUIstatus','IClamp_gain');
                    app.DropDown2.Value = g;
            end
            
            m = getpref('MC700AGUIstatus','mode2');
            
            switch m
                case 'VClamp'
                    app.TabGroup2.SelectedTab = app.Tab3;
                    g = getpref('MC700AGUIstatus','VClamp_gain2');
                    app.DropDown3.Value = g;
                case 'IClamp'
                    app.TabGroup2.SelectedTab = app.Tab4;
                    g = getpref('MC700AGUIstatus','IClamp_gain2');
                    app.DropDown4.Value = g;
            end                    
            
        end

        % DropDown value changed function
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            setpref('MC700AGUIstatus','VClamp_gain',value)
        end

        % DropDown2 value changed function
        function DropDown2ValueChanged(app, event)
            value = app.DropDown2.Value;
            setpref('MC700AGUIstatus','IClamp_gain',value)
        end

        % UIFigure close request function
        function UIFigureCloseRequest(app)
            setpref('MC700AGUIstatus','status',0)
            delete(app);
        end

        % DropDown3 value changed function
        function DropDown3ValueChanged(app, event)
            value = app.DropDown3.Value;
            setpref('MC700AGUIstatus','VClamp_gain2',value)
            
        end

        % DropDown4 value changed function
        function DropDown4ValueChanged(app, event)
            value = app.DropDown4.Value;
            setpref('MC700AGUIstatus','IClamp_gain2',value)
            
        end

        % TabGroup selection changed function
        function TabGSelectionChanged(app)
            selectedTab = app.TabGroup.SelectedTab;
            setpref('MC700AGUIstatus','mode',selectedTab.Title)
            
            switch selectedTab.Title
                case 'VClamp'
                    value = app.DropDown.Value;
                    setpref('MC700AGUIstatus','VClamp_gain',value)
                case 'IClamp'
                    value = app.DropDown2.Value;
                    setpref('MC700AGUIstatus','IClamp_gain',value)
            end                                
            
        end

        % TabGroup2 selection changed function
        function TabG2SelectionChanged(app)
            selectedTab = app.TabGroup2.SelectedTab;
            setpref('MC700AGUIstatus','mode2',selectedTab.Title)
            
            switch selectedTab.Title
                case 'VClamp'
                    value = app.DropDown3.Value;
                    setpref('MC700AGUIstatus','VClamp_gain2',value)
                case 'IClamp'
                    value = app.DropDown4.Value;
                    setpref('MC700AGUIstatus','IClamp_gain2',value)
            end                    
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MC700A';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest);
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'VClamp';
            app.LabelDropDown = uilabel(app.Tab);
            app.LabelDropDown.Text = 'Gain';
            app.DropDown = uidropdown(app.Tab);
            app.DropDown.Items = {'5', '10', '20', '50'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '5';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'IClamp';
            app.LabelDropDown2 = uilabel(app.Tab2);
            app.LabelDropDown2.Text = 'Gain';
            app.DropDown2 = uidropdown(app.Tab2);
            app.DropDown2.Items = {'20', '50'};
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            app.DropDown2.Value = '20';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TabGroup2.Units = 'pixels';
            app.Tab3 = uitab(app.TabGroup2);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'VClamp';
            app.Label = uilabel(app.Tab3);
            app.Label.Text = 'Gain';
            app.DropDown3 = uidropdown(app.Tab3);
            app.DropDown3.Items = {'5', '10', '20', '50'};
            app.DropDown3.ValueChangedFcn = createCallbackFcn(app, @DropDown3ValueChanged, true);
            app.DropDown3.Value = '5';
            app.Tab4 = uitab(app.TabGroup2);
            app.Tab4.Units = 'pixels';
            app.Tab4.Title = 'IClamp';
            app.Label2 = uilabel(app.Tab4);
            app.Label2.Text = 'Gain';
            app.DropDown4 = uidropdown(app.Tab4);
            app.DropDown4.Items = {'20', '50'};
            app.DropDown4.ValueChangedFcn = createCallbackFcn(app, @DropDown4ValueChanged, true);
            app.DropDown4.Value = '20';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'MultiClamp700A';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'MultiClamp700AAux';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MultiClamp700AGUI_Tab()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end